classdef TestApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FaultFreeTab                    matlab.ui.container.Tab
        MaxtargetspeedEditField         matlab.ui.control.NumericEditField
        MaxtargetspeedEditFieldLabel    matlab.ui.control.Label
        TargetspeedmEditField           matlab.ui.control.NumericEditField
        TargetspeedmEditFieldLabel      matlab.ui.control.Label
        MaxsafedistanceEditField        matlab.ui.control.NumericEditField
        MaxsafedistanceEditFieldLabel   matlab.ui.control.Label
        SafedistancemEditField          matlab.ui.control.NumericEditField
        SafedistancemEditFieldLabel     matlab.ui.control.Label
        MaxinitialvehiclespeedEditField  matlab.ui.control.NumericEditField
        MaxinitialvehiclespeedEditFieldLabel  matlab.ui.control.Label
        InitialvehiclespeedmsEditField  matlab.ui.control.NumericEditField
        InitialvehiclespeedmsEditFieldLabel  matlab.ui.control.Label
        NumberofsumilationsEditField    matlab.ui.control.NumericEditField
        NumberofsumilationsEditFieldLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        FaultyTab                       matlab.ui.container.Tab
        FaultsoptionsPanel              matlab.ui.container.Panel
        NumofsimulationsEditField       matlab.ui.control.NumericEditField
        NumofsimulationsEditFieldLabel  matlab.ui.control.Label
        delButton                       matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        FaultperiodicitysSlider         matlab.ui.control.Slider
        FaultperiodicitysSliderLabel    matlab.ui.control.Label
        FaultdurationEditField          matlab.ui.control.NumericEditField
        FaultdurationEditFieldLabel     matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        FaulttypeDropDown               matlab.ui.control.DropDown
        FaulttypeDropDownLabel          matlab.ui.control.Label
        MagnitudeEditField              matlab.ui.control.NumericEditField
        MagnitudeEditFieldLabel         matlab.ui.control.Label
        SimulationparametersGlobalPanel  matlab.ui.container.Panel
        MaxtargetspeedEditField_2       matlab.ui.control.NumericEditField
        MaxtargetspeedEditField_2Label  matlab.ui.control.Label
        TargetspeedmEditField_2         matlab.ui.control.NumericEditField
        TargetspeedmEditField_2Label    matlab.ui.control.Label
        MaxsafedistanceEditField_2      matlab.ui.control.NumericEditField
        MaxsafedistanceEditField_2Label  matlab.ui.control.Label
        SafedistancemEditField_2        matlab.ui.control.NumericEditField
        SafedistancemEditField_2Label   matlab.ui.control.Label
        MaxinitialvehiclespeedEditField_2  matlab.ui.control.NumericEditField
        MaxinitialvehiclespeedEditField_2Label  matlab.ui.control.Label
        InitialvehiclespeedmsEditField_2  matlab.ui.control.NumericEditField
        InitialvehiclespeedmsEditField_2Label  matlab.ui.control.Label
        GeneratefaultydataButton        matlab.ui.control.Button
    end


    properties (Access = private)
        d
        faultCounter
        offsetCounter
        noiseCounter
        stuckAtCounter
        %% Sim models
        simMdl          % Base sim model
        simMdlFaulty    % Sim model with FIBlock

        %% Variable params
        % Initial condition for the ego car
        v0_ego          % Initial speed of the ego car           (m/s)
        v0_range
        % Linear model for ACC design
        %% Automatic Cruise Control (ACC) Controller Parameters
        v_set           % ACC set speed                         (m/s)
        v_set_range
        default_spacing % ACC default spacing                   (m)
        default_spacing_range

        %%
        varNames = ["timestamp","value","is_anomaly"]
        folder
    end

    methods (Access = private)

        function r = checkEditFields(app)
            r = false;
            if app.InitialvehiclespeedmsEditField.Value > app.MaxinitialvehiclespeedEditField.Value ...
                    || app.TargetspeedmEditField.Value > app.MaxtargetspeedEditField.Value ...
                    || app.SafedistancemEditField.Value > app.MaxsafedistanceEditField.Value
                uialert(app.UIFigure, "Max input values must be higher than min input values", "Invalid inputs")
                r=true;
            end
        end

        function vals = generarateValues(app)
            simNum = app.NumberofsumilationsEditField.Value;
            vals = randi(simNum, [simNum, 3]);
            vals(:, 1) = rescale(vals(:, 1), app.InitialvehiclespeedmsEditField.Value, app.MaxinitialvehiclespeedEditField.Value);
            vals(:, 2) = rescale(vals(:, 2), app.TargetspeedmEditField.Value, app.MaxtargetspeedEditField.Value);
            vals(:, 3) = rescale(vals(:, 3), app.SafedistancemEditField.Value, app.MaxsafedistanceEditField.Value);
        end

        function multiSimulate(app, randVals)
            for i = 1:app.NumberofsumilationsEditField.Value
                app.d.Title = sprintf("Generating fault-free scenario %s", num2str(i));
                app.v0_ego = randVals(i, 1);
                app.v_set = randVals(i, 2);
                app.default_spacing = randVals(i, 3);
                app.assignVars()
                sim(app.simMdl)
                data = app.getValsArrayFaultFree(logsout);
                app.exportAsCSV(data, true, i)
            end
            uialert(app.UIFigure, sprintf('Saved data to %s', app.folder), 'Data saved', Icon='success');
        end

        function assignVars(app)
            assignin("base", "v0_ego", app.v0_ego)
            assignin("base", "v_set", app.v_set)
            assignin("base", "default_spacing", app.default_spacing)
            m = evalin("base", "m");
            Iz = evalin("base", "Iz");
            lf = evalin("base", "lf");
            lr = evalin("base", "lr");
            Cf = evalin("base", "Cf");
            Cr = evalin("base", "Cr");
            tau = evalin("base", "tau");
            assignin("base", "G", helperACCLinearModel(m,Iz,lf,lr,Cf,Cr,tau,app.v0_ego))
        end
        

        function runFaultFreeSim(app)
            app.folder = fullfile(pwd, datestr(now,'mm-dd-yyyy_HH-MM'));
            wasModelLoaded = bdIsLoaded(app.simMdl);
            if ~wasModelLoaded
                load_system(app.simMdl)
            end
            if app.NumberofsumilationsEditField.Value == 1
                evalin("base", "helperACCSetUp")
                app.v0_ego = app.InitialvehiclespeedmsEditField.Value;
                app.v_set = app.TargetspeedmEditField.Value;
                app.default_spacing = app.SafedistancemEditField.Value;
                app.assignVars()
                app.d.Title = sprintf("Generating fault-free scenario %s", num2str(1));
                sim(app.simMdl);
                data = app.getValsArrayFaultFree(logsout);
                app.exportAsCSV(data, true, 1)
                uialert(app.UIFigure, sprintf('Saved data to %s', app.folder), 'Data saved', Icon='success');
            else
                if ~app.checkEditFields()
                    evalin("base", "helperACCSetUp")
                    randVals = app.generarateValues();
                    app.multiSimulate(randVals)
                end
            end
            if wasModelLoaded
                close_system(app.simMdl)
            end
        end

        function exportAsCSV(app, data, train, fileNbr)
            if train
                n_folder = fullfile(app.folder, "train");
            else
                n_folder = fullfile(app.folder, "test");
            end
            if ~exist(n_folder, 'dir')
                mkdir(n_folder)
            end            
            fileName = fullfile(n_folder, sprintf('%s.csv', num2str(fileNbr)));
            table = array2table(data);
            table.Properties.VariableNames = app.varNames;
            writetable(table, fileName);
        end

        function exportAsCSVFault(app, data, faultType, fileNbr)
            
            n_folder = fullfile(app.folder, faultType);
            
            if ~exist(n_folder, 'dir')
                mkdir(n_folder)
            end            
            fileName = fullfile(n_folder, sprintf(strcat(faultType, '_%s.csv'), num2str(fileNbr)));
            table = array2table(data);
            table.Properties.VariableNames = app.varNames;
            writetable(table, fileName);
        end
        
        function data = getValsArrayFaultFree(~, logsout)
            timestamps = (1:length(logsout.getElement('ego_velocity').Values.Data))';
            values = logsout.getElement('ego_velocity').Values.Data;
            labels = zeros(length(logsout.getElement('ego_velocity').Values.Data), 1);
            data = [timestamps, values, labels];
        end

        function data = getValsArrayFaulty(~, logsout)
            timestamps = (1:length(logsout.getElement('ego_velocity').Values.Data))';
            values = logsout.getElement('ego_velocity').Values.Data;
            labels = downsample(logsout.getElement('labels').Values.Data, 20);
            data = [timestamps, values, labels];
        end

        function oldPointer = beginTask(app)
            oldPointer = get(app.UIFigure, 'pointer');
            app.UIFigure.Pointer = "watch";
            %set(app.UIFigure, 'Pointer', 'watch');
            drawnow;
        end
        
        function endTask(app, oldPointer)
            app.UIFigure.Pointer = oldPointer;
            %set(app.UIFigure, 'Pointer', oldPointer);
        end
        
        function runFaultySim(app)
            app.folder = fullfile(pwd, strcat(datestr(now,'mm-dd-yyyy_HH-MM'), "_faulty"));
            wasModelLoaded = bdIsLoaded(app.simMdlFaulty);
            if ~wasModelLoaded
                load_system(app.simMdlFaulty)
            end
            faults = app.ListBox.ItemsData;
            evalin("base", "helperACCSetUp")
            for i =1:length(faults)
                app.generateFaultyData(faults(i))
            end
            
            if wasModelLoaded
                save_system(app.simMdlFaulty)
                close_system(app.simMdlFaulty)
            end
            
        end   
        
        function generateFaultyData(app, options)
            vals = generateRandFaultyVals(app, options);
            switch options.faultType
                case "Offset"
                    app.set_periodicity(options)
                    for i = 1:options.simNums
                        app.d.Title = sprintf("Generating offset data %s", num2str(i));
                        app.generateOffset(app.simMdlFaulty, vals(i,:), options)
                    end
                case "Noise"
                    app.set_periodicity(options)
                    for i = 1:options.simNums
                        app.d.Title = sprintf("Generating noise data %s", num2str(i));

                        app.generateNoise(app.simMdlFaulty, vals(i,:), options)
                    end
                case "Stuck-at"
                    app.set_periodicity(options)
                    for i = 1:options.simNums
                        app.d.Title = sprintf("Generating stuck-at data %s", num2str(i));

                        app.generateStuckAt(app.simMdlFaulty, vals(i,:), options)
                    end
            end
        end
        
        function vals = generateRandFaultyVals(app, options)
            simNum = options.simNums;
            if simNum == 1
                vals = [app.InitialvehiclespeedmsEditField_2.Value, ...
                    app.TargetspeedmEditField_2.Value, ...
                    app.SafedistancemEditField_2.Value, ...
                    options.magnitude, ...
                    options.duration];
                return
            end


            vals = randi(simNum, [simNum, 5]);
            vals(:, 1) = rescale(vals(:, 1), app.InitialvehiclespeedmsEditField_2.Value,max(app.InitialvehiclespeedmsEditField_2.Value, app.MaxinitialvehiclespeedEditField_2.Value));
            vals(:, 2) = rescale(vals(:, 2), app.TargetspeedmEditField_2.Value, max(app.TargetspeedmEditField_2.Value, app.MaxtargetspeedEditField_2.Value));
            vals(:, 3) = rescale(vals(:, 3), app.SafedistancemEditField_2.Value, max(app.SafedistancemEditField_2.Value, app.MaxsafedistanceEditField_2.Value));
            vals(:, 4) = rescale(vals(:, 1), 0.2, options.magnitude);
            vals(:, 5) = rescale(vals(:, 1), 0.5, options.duration);
        end
        
        function generateStuckAt(app, simMdl, vals, options)
            FIBlock = Simulink.Mask.get(strcat(app.simMdlFaulty,'/FIBlock'));
            SpeedSensorFInjEn = FIBlock.Parameters(2);
            SpeedSensorFaultType = FIBlock.Parameters(3);
            SpeedSensorFaultEvent = FIBlock.Parameters(5);
            SpeedSensorEventValue = FIBlock.Parameters(6);
            SpeedSensorFaultEffect = FIBlock.Parameters(7);
            SpeedSensorEffectValue = FIBlock.Parameters(8);

            SpeedSensorFInjEn.set('Value', 'on');
            SpeedSensorFaultType.set('Value', 'Stuck-at');
            SpeedSensorFaultEvent.set('Value', 'Deterministic');
            SpeedSensorEventValue.set('Value', string(options.periodicity));
            SpeedSensorFaultEffect.set('Value', 'Constant time');
            SpeedSensorEffectValue.set('Value', string(vals(5)));

            app.v0_ego = vals(1);
            app.v_set = vals(2);
            app.default_spacing = vals(3);
            app.assignVars()

            sim(simMdl)

            data = app.getValsArrayFaulty(logsout);
            app.exportAsCSVFault(data, "Stuck-At", app.stuckAtCounter)
            app.stuckAtCounter = app.stuckAtCounter + 1;
        end
        
        function generateNoise(app, simMdl, vals, options) 
            FIBlock = Simulink.Mask.get(strcat(app.simMdlFaulty,'/FIBlock'));

            SpeedSensorFInjEn = FIBlock.Parameters(2);
            SpeedSensorFaultType = FIBlock.Parameters(3);
            SpeedSensorFaultValue = FIBlock.Parameters(4);
            SpeedSensorFaultEvent = FIBlock.Parameters(5);
            SpeedSensorEventValue = FIBlock.Parameters(6);
            SpeedSensorFaultEffect = FIBlock.Parameters(7);
            SpeedSensorEffectValue = FIBlock.Parameters(8);

            SpeedSensorFInjEn.set('Value', 'on');
            SpeedSensorFaultType.set('Value', 'Noise');
            SpeedSensorFaultValue.set('Value', string(vals(4)));
            SpeedSensorFaultEvent.set('Value', 'Deterministic');
            SpeedSensorEventValue.set('Value', string(options.periodicity));
            SpeedSensorFaultEffect.set('Value', 'Constant time');
            SpeedSensorEffectValue.set('Value', string(vals(5)));

            app.v0_ego = vals(1);
            app.v_set = vals(2);
            app.default_spacing = vals(3);
            app.assignVars()

            sim(simMdl)

            data = app.getValsArrayFaulty(logsout);
            app.exportAsCSVFault(data, "Noise", app.noiseCounter)
            app.noiseCounter = app.noiseCounter + 1;
        end
        
        function generateOffset(app, simMdl, vals, options)  
            FIBlock = Simulink.Mask.get(strcat(app.simMdlFaulty,'/FIBlock'));
            SpeedSensorFInjEn = FIBlock.Parameters(2);
            SpeedSensorFaultType = FIBlock.Parameters(3);
            SpeedSensorFaultValue = FIBlock.Parameters(4);
            SpeedSensorFaultEvent = FIBlock.Parameters(5);
            SpeedSensorEventValue = FIBlock.Parameters(6);
            SpeedSensorFaultEffect = FIBlock.Parameters(7);
            SpeedSensorEffectValue = FIBlock.Parameters(8);

            SpeedSensorFInjEn.set('Value', 'on');
            SpeedSensorFaultType.set('Value', 'Bias/Offset');
            SpeedSensorFaultValue.set('Value', string(vals(4)));
            SpeedSensorFaultEvent.set('Value', 'Deterministic');
            SpeedSensorEventValue.set('Value', string(options.periodicity));
            SpeedSensorFaultEffect.set('Value', 'Constant time');
            SpeedSensorEffectValue.set('Value', string(vals(5)));

            app.v0_ego = vals(1);
            app.v_set = vals(2);
            app.default_spacing = vals(3);
            app.assignVars()

            sim(simMdl)

            data = app.getValsArrayFaulty(logsout);
            app.exportAsCSVFault(data, "Offset", app.offsetCounter)
            app.offsetCounter = app.offsetCounter + 1;
        end
        
        function set_periodicity(app, options)
            set_param(strcat(app.simMdlFaulty, "/Pulse Generator"), "Period", string(options.periodicity))
            PulseWidht = (0.005*round(options.periodicity*0.01/0.005))*100/options.periodicity;
            set_param(strcat(app.simMdlFaulty, "/Pulse Generator"), "PulseWidth", string(PulseWidht))
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simMdl = "ACCTestBenchExample";
            app.faultCounter = 1;
            app.simMdlFaulty = "FaultyGeneration";
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            oldPointer = app.beginTask();
            drawnow;

            app.d = uiprogressdlg(app.UIFigure, Title=sprintf('Load Simulink model...'),...
                Indeterminate='on');
            drawnow;
            
            app.runFaultFreeSim()

            close(app.d);
            app.endTask(oldPointer);
        end

        % Value changed function: NumberofsumilationsEditField
        function NumberofsumilationsEditFieldValueChanged(app, event)
            value = app.NumberofsumilationsEditField.Value;
            if value > 1
                app.MaxinitialvehiclespeedEditField.Editable = "on";
                app.MaxsafedistanceEditField.Editable = "on";
                app.MaxtargetspeedEditField.Editable = "on";

                app.TargetspeedmEditFieldLabel.Text = "Min target speed (m/s)";
                app.InitialvehiclespeedmsEditFieldLabel.Text = "Min initial speed (m/s)";
                app.SafedistancemEditFieldLabel.Text = "Min safe distance (m)";
            else
                app.MaxinitialvehiclespeedEditField.Editable = "off";
                app.MaxsafedistanceEditField.Editable = "off";
                app.MaxtargetspeedEditField.Editable = "off";
                app.TargetspeedmEditFieldLabel.Text = "Target speed (m/s)";
                app.InitialvehiclespeedmsEditFieldLabel.Text = "Initial speed (m/s)";
                app.SafedistancemEditFieldLabel.Text = "Safe distance (m/s)";
            end
        end

        % Value changed function: FaulttypeDropDown
        function FaulttypeDropDownValueChanged(app, event)
            value = app.FaulttypeDropDown.Value;
            switch value
                case "Noise"
                    app.MagnitudeEditField.Enable = "on";
                    app.MagnitudeEditFieldLabel.Enable = "on";
                    app.MagnitudeEditFieldLabel.Text = "Boundaries (%)";
                case "Offset"
                    app.MagnitudeEditField.Enable = "on";
                    app.MagnitudeEditFieldLabel.Enable = "on";
                    app.MagnitudeEditFieldLabel.Text = "Magnitude (m/s)";
                case "Stuck-at"
                    app.MagnitudeEditFieldLabel.Enable = "off";
                    app.MagnitudeEditField.Enable = "off";
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.FaultdurationEditField.Value > app.FaultperiodicitysSlider.Value
                uialert(app.UIFigure, "Fault duration must be lower than periodicity", "Invalid input")
                return
            end
            data.simNums = app.NumofsimulationsEditField.Value;
            data.faultType = app.FaulttypeDropDown.Value;
            data.magnitude = app.MagnitudeEditField.Value;
            data.periodicity = app.FaultperiodicitysSlider.Value;
            data.duration = app.FaultdurationEditField.Value;
            name = strcat(app.FaulttypeDropDown.Value, sprintf("_%s", num2str(app.faultCounter)));  
            data.name = name;
            app.ListBox.Items = [app.ListBox.Items, name];
            app.ListBox.ItemsData = [app.ListBox.ItemsData, data];
            app.faultCounter = app.faultCounter + 1;
        end

        % Button pushed function: delButton
        function delButtonPushed(app, event)
            try
                [~,idx] = ismember(app.ListBox.Value.name,app.ListBox.Items);
                app.ListBox.Items(idx) = [];
                app.ListBox.ItemsData(idx) = [];
            catch
            end
            if isempty(app.ListBox.Items)
                app.faultCounter = 1;
            end
        end

        % Button pushed function: GeneratefaultydataButton
        function GeneratefaultydataButtonPushed(app, event)
            app.offsetCounter = 1;
            app.noiseCounter = 1;
            app.stuckAtCounter = 1;
            if isempty(app.ListBox.Items)
                uialert(app.UIFigure, "Please add faults", "Fault list empty")
                return
            end
            app.d = uiprogressdlg(app.UIFigure, Title=sprintf('Load Simulink model...'),...
                Indeterminate='on');

            app.runFaultySim();

            close(app.d);
        end

        % Value changed function: FaultdurationEditField
        function FaultdurationEditFieldValueChanged(app, event)
            value = app.FaultdurationEditField.Value;
            value = round(value/0.005)*0.005;
            app.FaultdurationEditField.Value = value;
            app.FaultperiodicitysSlider.Value = round(max(value, app.FaultperiodicitysSlider.Value)/0.005)*0.005;
        end

        % Value changed function: NumofsimulationsEditField
        function NumofsimulationsEditFieldValueChanged(app, event)
            value = app.NumofsimulationsEditField.Value;
            if value > 1
                app.MaxinitialvehiclespeedEditField_2.Editable = "on";
                app.MaxsafedistanceEditField_2.Editable = "on";
                app.MaxtargetspeedEditField_2.Editable = "on";

                app.TargetspeedmEditField_2Label.Text = "Min target speed (m/s)";
                app.InitialvehiclespeedmsEditField_2Label.Text = "Min initial speed (m/s)";
                app.SafedistancemEditField_2Label.Text = "Min safe distance (m)";
            else
                app.MaxinitialvehiclespeedEditField_2.Editable = "off";
                app.MaxsafedistanceEditField_2.Editable = "off";
                app.MaxtargetspeedEditField_2.Editable = "off";
                app.TargetspeedmEditField_2Label.Text = "Target speed (m/s)";
                app.InitialvehiclespeedmsEditField_2Label.Text = "Initial speed (m/s)";
                app.SafedistancemEditField_2Label.Text = "Safe distance (m/s)";
            end
        end

        % Value changed function: FaultperiodicitysSlider
        function FaultperiodicitysSliderValueChanged(app, event)
            value = app.FaultperiodicitysSlider.Value;
            value = round(value/0.005)*0.005;
            app.FaultperiodicitysSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FaultFreeTab = uitab(app.TabGroup);
            app.FaultFreeTab.Title = 'Fault Free';
            app.SimulateButton = uibutton(app.FaultFreeTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.NumberofsumilationsEditFieldLabel = uilabel(app.FaultFreeTab);
            app.NumberofsumilationsEditFieldLabel.Text = 'Number of sumilations';
            app.NumberofsumilationsEditField = uieditfield(app.FaultFreeTab, 'numeric');
            app.NumberofsumilationsEditField.Limits = [1 Inf];
            app.NumberofsumilationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsumilationsEditFieldValueChanged, true);
            app.NumberofsumilationsEditField.Value = 1;
            app.InitialvehiclespeedmsEditFieldLabel = uilabel(app.FaultFreeTab);
            app.InitialvehiclespeedmsEditFieldLabel.Text = 'Initial vehicle speed (m/s)';
            app.InitialvehiclespeedmsEditField = uieditfield(app.FaultFreeTab, 'numeric');
            app.InitialvehiclespeedmsEditField.Limits = [1 Inf];
            app.InitialvehiclespeedmsEditField.Value = 20.6;
            app.MaxinitialvehiclespeedEditFieldLabel = uilabel(app.FaultFreeTab);
            app.MaxinitialvehiclespeedEditFieldLabel.Text = 'Max initial vehicle speed';
            app.MaxinitialvehiclespeedEditField = uieditfield(app.FaultFreeTab, 'numeric');
            app.MaxinitialvehiclespeedEditField.Limits = [1 Inf];
            app.MaxinitialvehiclespeedEditField.Editable = 'off';
            app.MaxinitialvehiclespeedEditField.Value = 1;
            app.SafedistancemEditFieldLabel = uilabel(app.FaultFreeTab);
            app.SafedistancemEditFieldLabel.Text = 'Safe distance (m)';
            app.SafedistancemEditField = uieditfield(app.FaultFreeTab, 'numeric');
            app.SafedistancemEditField.Limits = [1 Inf];
            app.SafedistancemEditField.Value = 5;
            app.MaxsafedistanceEditFieldLabel = uilabel(app.FaultFreeTab);
            app.MaxsafedistanceEditFieldLabel.Text = 'Max safe distance';
            app.MaxsafedistanceEditField = uieditfield(app.FaultFreeTab, 'numeric');
            app.MaxsafedistanceEditField.Limits = [1 Inf];
            app.MaxsafedistanceEditField.Editable = 'off';
            app.MaxsafedistanceEditField.Value = 1;
            app.TargetspeedmEditFieldLabel = uilabel(app.FaultFreeTab);
            app.TargetspeedmEditFieldLabel.Text = 'Target speed (m)';
            app.TargetspeedmEditField = uieditfield(app.FaultFreeTab, 'numeric');
            app.TargetspeedmEditField.Limits = [1 Inf];
            app.TargetspeedmEditField.Value = 21.5;
            app.MaxtargetspeedEditFieldLabel = uilabel(app.FaultFreeTab);
            app.MaxtargetspeedEditFieldLabel.Text = 'Max target speed';
            app.MaxtargetspeedEditField = uieditfield(app.FaultFreeTab, 'numeric');
            app.MaxtargetspeedEditField.Limits = [1 Inf];
            app.MaxtargetspeedEditField.Editable = 'off';
            app.MaxtargetspeedEditField.Value = 1;
            app.FaultyTab = uitab(app.TabGroup);
            app.FaultyTab.Title = 'Faulty';
            app.GeneratefaultydataButton = uibutton(app.FaultyTab, 'push');
            app.GeneratefaultydataButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratefaultydataButtonPushed, true);
            app.GeneratefaultydataButton.Text = 'Generate faulty data';
            app.SimulationparametersGlobalPanel = uipanel(app.FaultyTab);
            app.SimulationparametersGlobalPanel.Title = 'Simulation parameters (Global)';
            app.InitialvehiclespeedmsEditField_2Label = uilabel(app.SimulationparametersGlobalPanel);
            app.InitialvehiclespeedmsEditField_2Label.Text = 'Initial vehicle speed (m/s)';
            app.InitialvehiclespeedmsEditField_2 = uieditfield(app.SimulationparametersGlobalPanel, 'numeric');
            app.InitialvehiclespeedmsEditField_2.Limits = [1 Inf];
            app.InitialvehiclespeedmsEditField_2.Value = 20.6;
            app.MaxinitialvehiclespeedEditField_2Label = uilabel(app.SimulationparametersGlobalPanel);
            app.MaxinitialvehiclespeedEditField_2Label.Text = 'Max initial vehicle speed';
            app.MaxinitialvehiclespeedEditField_2 = uieditfield(app.SimulationparametersGlobalPanel, 'numeric');
            app.MaxinitialvehiclespeedEditField_2.Limits = [1 Inf];
            app.MaxinitialvehiclespeedEditField_2.Editable = 'off';
            app.MaxinitialvehiclespeedEditField_2.Value = 1;
            app.SafedistancemEditField_2Label = uilabel(app.SimulationparametersGlobalPanel);
            app.SafedistancemEditField_2Label.Text = 'Safe distance (m)';
            app.SafedistancemEditField_2 = uieditfield(app.SimulationparametersGlobalPanel, 'numeric');
            app.SafedistancemEditField_2.Limits = [1 Inf];
            app.SafedistancemEditField_2.Value = 5;
            app.MaxsafedistanceEditField_2Label = uilabel(app.SimulationparametersGlobalPanel);
            app.MaxsafedistanceEditField_2Label.Text = 'Max safe distance';
            app.MaxsafedistanceEditField_2 = uieditfield(app.SimulationparametersGlobalPanel, 'numeric');
            app.MaxsafedistanceEditField_2.Limits = [1 Inf];
            app.MaxsafedistanceEditField_2.Editable = 'off';
            app.MaxsafedistanceEditField_2.Value = 1;
            app.TargetspeedmEditField_2Label = uilabel(app.SimulationparametersGlobalPanel);
            app.TargetspeedmEditField_2Label.Text = 'Target speed (m)';
            app.TargetspeedmEditField_2 = uieditfield(app.SimulationparametersGlobalPanel, 'numeric');
            app.TargetspeedmEditField_2.Limits = [1 Inf];
            app.TargetspeedmEditField_2.Value = 21.5;
            app.MaxtargetspeedEditField_2Label = uilabel(app.SimulationparametersGlobalPanel);
            app.MaxtargetspeedEditField_2Label.Text = 'Max target speed';
            app.MaxtargetspeedEditField_2 = uieditfield(app.SimulationparametersGlobalPanel, 'numeric');
            app.MaxtargetspeedEditField_2.Limits = [1 Inf];
            app.MaxtargetspeedEditField_2.Editable = 'off';
            app.MaxtargetspeedEditField_2.Value = 1;
            app.FaultsoptionsPanel = uipanel(app.FaultyTab);
            app.FaultsoptionsPanel.Title = 'Faults options';
            app.MagnitudeEditFieldLabel = uilabel(app.FaultsoptionsPanel);
            app.MagnitudeEditFieldLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.FaultsoptionsPanel, 'numeric');
            app.MagnitudeEditField.Limits = [0.2 Inf];
            app.MagnitudeEditField.Value = 0.2;
            app.FaulttypeDropDownLabel = uilabel(app.FaultsoptionsPanel);
            app.FaulttypeDropDownLabel.Text = 'Fault type';
            app.FaulttypeDropDown = uidropdown(app.FaultsoptionsPanel);
            app.FaulttypeDropDown.Items = {'Offset', 'Noise', 'Stuck-at'};
            app.FaulttypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FaulttypeDropDownValueChanged, true);
            app.FaulttypeDropDown.Value = 'Offset';
            app.AddButton = uibutton(app.FaultsoptionsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.FaultdurationEditFieldLabel = uilabel(app.FaultsoptionsPanel);
            app.FaultdurationEditFieldLabel.Text = 'Fault duration';
            app.FaultdurationEditField = uieditfield(app.FaultsoptionsPanel, 'numeric');
            app.FaultdurationEditField.Limits = [0.5 Inf];
            app.FaultdurationEditField.ValueChangedFcn = createCallbackFcn(app, @FaultdurationEditFieldValueChanged, true);
            app.FaultdurationEditField.Value = 0.5;
            app.FaultperiodicitysSliderLabel = uilabel(app.FaultsoptionsPanel);
            app.FaultperiodicitysSliderLabel.Text = 'Fault periodicity (s)';
            app.FaultperiodicitysSlider = uislider(app.FaultsoptionsPanel);
            app.FaultperiodicitysSlider.Limits = [0 17];
            app.FaultperiodicitysSlider.ValueChangedFcn = createCallbackFcn(app, @FaultperiodicitysSliderValueChanged, true);
            app.ListBox = uilistbox(app.FaultsoptionsPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.delButton = uibutton(app.FaultsoptionsPanel, 'push');
            app.delButton.ButtonPushedFcn = createCallbackFcn(app, @delButtonPushed, true);
            app.delButton.Text = 'del';
            app.NumofsimulationsEditFieldLabel = uilabel(app.FaultsoptionsPanel);
            app.NumofsimulationsEditFieldLabel.Text = 'Num of simulations';
            app.NumofsimulationsEditField = uieditfield(app.FaultsoptionsPanel, 'numeric');
            app.NumofsimulationsEditField.Limits = [1 Inf];
            app.NumofsimulationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumofsimulationsEditFieldValueChanged, true);
            app.NumofsimulationsEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end